classdef robotGUI < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TabGroup                       matlab.ui.container.TabGroup
        MotorSpecificationsTab         matlab.ui.container.Tab
        PhaseResistanceohmsEditField   matlab.ui.control.NumericEditField
        PhaseResistanceohmsEditFieldLabel  matlab.ui.control.Label
        TotalGearReductionEfficiencyEditField  matlab.ui.control.NumericEditField
        TotalGearReductionEfficiencyEditFieldLabel  matlab.ui.control.Label
        GearReductionEditField         matlab.ui.control.NumericEditField
        GearReductionEditFieldLabel    matlab.ui.control.Label
        AppliedMotorVoltageEditField   matlab.ui.control.NumericEditField
        AppliedMotorVoltageEditFieldLabel  matlab.ui.control.Label
        MaxCurrentAEditField           matlab.ui.control.NumericEditField
        MaxCurrentAEditFieldLabel      matlab.ui.control.Label
        MotorEfficiencyEditField       matlab.ui.control.NumericEditField
        MotorEfficiencyEditFieldLabel  matlab.ui.control.Label
        UseDefaultValuesButton         matlab.ui.control.Button
        axleLength                     matlab.ui.control.NumericEditField
        OuterstatorradiusmmLabel_5     matlab.ui.control.Label
        ShowMotorPreviewButton         matlab.ui.control.Button
        rotorLength                    matlab.ui.control.NumericEditField
        OuterstatorradiusmmLabel_4     matlab.ui.control.Label
        statorLength                   matlab.ui.control.NumericEditField
        OuterstatorradiusmmLabel_3     matlab.ui.control.Label
        innerStatorDiameter            matlab.ui.control.NumericEditField
        OuterstatorradiusmmLabel_2     matlab.ui.control.Label
        outerStatorDiameter            matlab.ui.control.NumericEditField
        OuterstatorradiusmmLabel       matlab.ui.control.Label
        innerRotorDiameter             matlab.ui.control.NumericEditField
        InnerrotorradiusmmLabel        matlab.ui.control.Label
        outerRotorDiameter             matlab.ui.control.NumericEditField
        OuterrotorradiusmmLabel        matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        SystemParametersTab            matlab.ui.container.Tab
        UseDefaultValuesButton_2       matlab.ui.control.Button
        AxleMaterialDropDown           matlab.ui.control.DropDown
        AxleMaterialDropDownLabel      matlab.ui.control.Label
        StatorMaterialDropDown         matlab.ui.control.DropDown
        StatorMaterialDropDownLabel    matlab.ui.control.Label
        RotorMaterialDropDown          matlab.ui.control.DropDown
        RotorMaterialDropDownLabel     matlab.ui.control.Label
        BLDCMotorkVEditField           matlab.ui.control.NumericEditField
        BLDCMotorkVEditFieldLabel      matlab.ui.control.Label
        MaxMotorTemperatureEditField   matlab.ui.control.NumericEditField
        MaxMotorTemperatureEditFieldLabel  matlab.ui.control.Label
        AmbientTemperatureCEditField   matlab.ui.control.NumericEditField
        AmbientTemperatureCEditFieldLabel  matlab.ui.control.Label
        Tab                            matlab.ui.container.Tab
    end

    properties (Access = public)
        MotionAnlysisClass
    end
    
    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ShowMotorPreviewButton
        function ShowMotorPreviewButtonPushed(comp, event)
            MPA = MotionPowerAnalysis;

            % Define Input Values
            OuterStatorDiameter = comp.outerStatorDiameter.Value; % mm
            InnerStatorDiameter = comp.innerStatorDiameter.Value; % mm
            OuterRotorDiameter = comp.outerRotorDiameter.Value; % mm
            InnerRotorDiameter = comp.innerRotorDiameter.Value; % mm
            AxleLength = comp.axleLength.Value; % mm
            StatorLength = comp.statorLength.Value; % mm
            RotorLength = comp.rotorLength.Value; % mm

            % Create a new figure for the plot
            figPosition = [8,9,555,491];
            newFig = figure('Position', figPosition, 'Name', 'Motor Visualization', 'NumberTitle', 'off');
            ax = axes(newFig);
            hold(ax, 'on');

            % Plot the rotor as a solid cylinder
            MPA.plotSolidCylinder(ax, OuterRotorDiameter, InnerRotorDiameter, RotorLength, 'red', 0);

            % Plot the stator as a solid cylinder inside the rotor
            MPA.plotSolidCylinder(ax, OuterStatorDiameter, InnerStatorDiameter, StatorLength, 'blue', 0);

            % Plot the axle as a solid cylinder
            MPA.plotSolidCylinder(ax, InnerStatorDiameter, 0, AxleLength, 'green', 0);

            % Set axis properties
            axis(ax, 'equal');
            xlabel(ax, 'X [mm]');
            ylabel(ax, 'Y [mm]');
            zlabel(ax, 'Z [mm]');
            title(ax, 'Motor ');
            grid(ax, 'on');
            view(ax, 45, 45);
            hold(ax, 'off');


        end

        % Button pushed function: UseDefaultValuesButton
        function UseDefaultValuesButtonPushed(comp, event)
            % Define Default Input Values
            comp.outerStatorDiameter.Value = 29; % mm
            comp.innerStatorDiameter.Value = 6; % mm
            comp.outerRotorDiameter.Value = 35; % mm
            comp.innerRotorDiameter.Value = 30; % mm
            comp.axleLength.Value = 54; % mm
            comp.statorLength.Value = 36; % mm
            comp.rotorLength.Value = 36; % mm

            comp.AppliedMotorVoltageEditField.Value = 14.7; %V
            comp.MaxCurrentAEditField.Value = 38; % A
            comp.GearReductionEditField.Value = 32; 
            comp.MotorEfficiencyEditField.Value = 95; % represented as a percentage
            comp.TotalGearReductionEfficiencyEditField.Value = 90; % represented as a percentage
            comp.PhaseResistanceohmsEditField.Value = 0.055178; % ohms
        end

        % Button pushed function: UseDefaultValuesButton_2
        function UseDefaultValuesButton_2Pushed(comp, event)
            comp.AxleMaterialDropDown.Value='Steel';
            comp.RotorMaterialDropDown.Value='Aluminum';
            comp.StatorMaterialDropDown.Value='50:50 Steel & Cu';
            comp.AmbientTemperatureCEditField.Value = 24; % Celsius
            comp.MaxMotorTemperatureEditField.Value = 60; % This is a relatively conservative value
            comp.BLDCMotorkVEditField.Value = 910; % RPM/Volt 
            
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)


        end

        % Create the underlying components
        function setup(comp)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            comp.Position = [1 1 1308 672];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TabGroup = uitabgroup(comp);
            comp.MotorSpecificationsTab = uitab(comp.TabGroup);
            comp.MotorSpecificationsTab.Title = 'Motor Specifications';
            comp.Image = uiimage(comp.MotorSpecificationsTab);
            comp.Image.ImageSource = fullfile(pathToMLAPP, 'motorImage.png');
            comp.OuterrotorradiusmmLabel = uilabel(comp.MotorSpecificationsTab);
            comp.OuterrotorradiusmmLabel.Text = 'Outer rotor diameter (mm)';
            comp.outerRotorDiameter = uieditfield(comp.MotorSpecificationsTab, 'numeric');
            comp.InnerrotorradiusmmLabel = uilabel(comp.MotorSpecificationsTab);
            comp.InnerrotorradiusmmLabel.Text = 'Inner rotor diameter (mm)';
            comp.innerRotorDiameter = uieditfield(comp.MotorSpecificationsTab, 'numeric');
            comp.OuterstatorradiusmmLabel = uilabel(comp.MotorSpecificationsTab);
            comp.OuterstatorradiusmmLabel.Text = 'Outer stator diameter (mm)';
            comp.outerStatorDiameter = uieditfield(comp.MotorSpecificationsTab, 'numeric');
            comp.OuterstatorradiusmmLabel_2 = uilabel(comp.MotorSpecificationsTab);
            comp.OuterstatorradiusmmLabel_2.Text = 'Axle diameter (mm)';
            comp.innerStatorDiameter = uieditfield(comp.MotorSpecificationsTab, 'numeric');
            comp.OuterstatorradiusmmLabel_3 = uilabel(comp.MotorSpecificationsTab);
            comp.OuterstatorradiusmmLabel_3.Text = 'Stator Length (mm)';
            comp.statorLength = uieditfield(comp.MotorSpecificationsTab, 'numeric');
            comp.OuterstatorradiusmmLabel_4 = uilabel(comp.MotorSpecificationsTab);
            comp.OuterstatorradiusmmLabel_4.Text = 'Rotor Length (mm)';
            comp.rotorLength = uieditfield(comp.MotorSpecificationsTab, 'numeric');
            comp.ShowMotorPreviewButton = uibutton(comp.MotorSpecificationsTab, 'push');
            comp.ShowMotorPreviewButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @ShowMotorPreviewButtonPushed, true);
            comp.ShowMotorPreviewButton.Text = 'Show Motor Preview';
            comp.OuterstatorradiusmmLabel_5 = uilabel(comp.MotorSpecificationsTab);
            comp.OuterstatorradiusmmLabel_5.Text = 'Axle Length (mm)';
            comp.axleLength = uieditfield(comp.MotorSpecificationsTab, 'numeric');
            comp.UseDefaultValuesButton = uibutton(comp.MotorSpecificationsTab, 'push');
            comp.UseDefaultValuesButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @UseDefaultValuesButtonPushed, true);
            comp.UseDefaultValuesButton.Text = 'Use Default Values';
            comp.MotorEfficiencyEditFieldLabel = uilabel(comp.MotorSpecificationsTab);
            comp.MotorEfficiencyEditFieldLabel.Text = 'Motor Efficiency  (%)';
            comp.MotorEfficiencyEditField = uieditfield(comp.MotorSpecificationsTab, 'numeric');
            comp.MaxCurrentAEditFieldLabel = uilabel(comp.MotorSpecificationsTab);
            comp.MaxCurrentAEditFieldLabel.Text = 'Max Current (A)';
            comp.MaxCurrentAEditField = uieditfield(comp.MotorSpecificationsTab, 'numeric');
            comp.AppliedMotorVoltageEditFieldLabel = uilabel(comp.MotorSpecificationsTab);
            comp.AppliedMotorVoltageEditFieldLabel.Text = 'Applied Motor Voltage';
            comp.AppliedMotorVoltageEditField = uieditfield(comp.MotorSpecificationsTab, 'numeric');
            comp.GearReductionEditFieldLabel = uilabel(comp.MotorSpecificationsTab);
            comp.GearReductionEditFieldLabel.Text = 'Gear Reduction';
            comp.GearReductionEditField = uieditfield(comp.MotorSpecificationsTab, 'numeric');
            comp.TotalGearReductionEfficiencyEditFieldLabel = uilabel(comp.MotorSpecificationsTab);
            comp.TotalGearReductionEfficiencyEditFieldLabel.Text = 'Total Gear Reduction Efficiency (%)';
            comp.TotalGearReductionEfficiencyEditField = uieditfield(comp.MotorSpecificationsTab, 'numeric');
            comp.PhaseResistanceohmsEditFieldLabel = uilabel(comp.MotorSpecificationsTab);
            comp.PhaseResistanceohmsEditFieldLabel.Text = 'Phase Resistance (ohms)';
            comp.PhaseResistanceohmsEditField = uieditfield(comp.MotorSpecificationsTab, 'numeric');
            comp.SystemParametersTab = uitab(comp.TabGroup);
            comp.SystemParametersTab.Title = 'System Parameters';
            comp.AmbientTemperatureCEditFieldLabel = uilabel(comp.SystemParametersTab);
            comp.AmbientTemperatureCEditFieldLabel.Text = 'Ambient Temperature (C)';
            comp.AmbientTemperatureCEditField = uieditfield(comp.SystemParametersTab, 'numeric');
            comp.MaxMotorTemperatureEditFieldLabel = uilabel(comp.SystemParametersTab);
            comp.MaxMotorTemperatureEditFieldLabel.Text = 'Max Motor Temperature';
            comp.MaxMotorTemperatureEditField = uieditfield(comp.SystemParametersTab, 'numeric');
            comp.BLDCMotorkVEditFieldLabel = uilabel(comp.SystemParametersTab);
            comp.BLDCMotorkVEditFieldLabel.Text = 'BLDC Motor kV';
            comp.BLDCMotorkVEditField = uieditfield(comp.SystemParametersTab, 'numeric');
            comp.RotorMaterialDropDownLabel = uilabel(comp.SystemParametersTab);
            comp.RotorMaterialDropDownLabel.Text = 'Rotor Material';
            comp.RotorMaterialDropDown = uidropdown(comp.SystemParametersTab);
            comp.RotorMaterialDropDown.Items = {'Aluminum', 'Copper', 'Steel'};
            comp.RotorMaterialDropDown.Value = 'Aluminum';
            comp.StatorMaterialDropDownLabel = uilabel(comp.SystemParametersTab);
            comp.StatorMaterialDropDownLabel.Text = 'Stator Material';
            comp.StatorMaterialDropDown = uidropdown(comp.SystemParametersTab);
            comp.StatorMaterialDropDown.Items = {'50:50 Steel & Cu', 'Aluminum', 'Copper', 'Steel'};
            comp.StatorMaterialDropDown.Value = '50:50 Steel & Cu';
            comp.AxleMaterialDropDownLabel = uilabel(comp.SystemParametersTab);
            comp.AxleMaterialDropDownLabel.Text = 'Axle  Material';
            comp.AxleMaterialDropDown = uidropdown(comp.SystemParametersTab);
            comp.AxleMaterialDropDown.Items = {'Aluminum', 'Copper', 'Steel'};
            comp.AxleMaterialDropDown.Value = 'Steel';
            comp.UseDefaultValuesButton_2 = uibutton(comp.SystemParametersTab, 'push');
            comp.UseDefaultValuesButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @UseDefaultValuesButton_2Pushed, true);
            comp.UseDefaultValuesButton_2.Text = 'Use Default Values';
            comp.Tab = uitab(comp.TabGroup);
            comp.Tab.Title = 'Tab';
        end
    end
end